classdef app2game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        StartButton         matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        TimeEditFieldLabel  matlab.ui.control.Label
        TimeEditField       matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            tic  %start timer
            figure; hold on
            hold(app.UIAxes, 'on')
            axis([0 10 0 10])
            axis(app.UIAxes,[0 10 0 10])
            %figure;ginput
            for i = 1:2 % loop to display one target at the time
                x_cir = rand*10; %- location of target
                y_cir = rand*10;
                plot(x_cir, y_cir,'o');
                plot(app.UIAxes,x_cir, y_cir,'o');
                [x y] = ginput(1);  %- location of mouse click
                plot(x,y,'x');
                plot(app.UIAxes,x, y,'x');
                dist_cir = sqrt((x_cir - x)^2 + (y_cir - y)^2); % distance between target and click
                while dist_cir > 0.2
                    %         x_cir = rand*10;
                    %         y_cir = rand*10;
                    %         plot(x_cir, y_cir,'o');
                    [x, y] = ginput(1);
                    plot(x,y,'x');
                    dist_cir = sqrt((x_cir - x)^2 + (y_cir - y)^2); % distance between target and click
                end
            end
            t = toc % total elapsed time
            
            
            if t <20
                text (5,5,'Good job!')
                text (app.UIAxes,5,5,'Good job!')
            end           
            app.TimeEditField.Value = t;
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2game

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end